classdef RoverRoute < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UploadButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        AddRowButton                matlab.ui.control.Button
        PlotOptionsDropDownLabel    matlab.ui.control.Label
        PlotOptionsDropDown         matlab.ui.control.DropDown
        PlotButton                  matlab.ui.control.Button
        RouteLengthEditFieldLabel   matlab.ui.control.Label
        RouteLengthEditField        matlab.ui.control.NumericEditField
        CalculateRouteLengthButton  matlab.ui.control.Button
        RoverRoutePlannerLabel      matlab.ui.control.Label
        WaypointsLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        %DEM File Data
        grid 
        dX
        dY
        gridsize
        
        %table variables
        name
        X
        Y
        time
        plotCheck
        
        %Routes
        RouteX
        RouteY
        RouteExists
    end
    
    
    methods (Access = private)
        
        function updateTable(app)
           %pull table data
            tableData=get(app.UITable,'Data')
            
            %assign data
            for ii=1:size(tableData,1)
                app.name(ii)=tableData{ii,1};
                app.X(ii)=tableData{ii,2};
                app.Y(ii)=tableData{ii,3};
                app.time(ii)=tableData{ii,4};
                app.plotCheck(ii)=tableData{ii,5};
            end
            
            %Push data
            app.UITable.Data=tableData; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data={' ',0,0,0,false};
            updateTable(app);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            %load data file
            Data=load('DEM.mat');
            %assign file variables to app private properties
            app.grid=Data.grid;
            app.gridsize=Data.gridsize;
            app.dX=Data.dX;
            app.dY=Data.dY;
            %plot the grid on the axes component
            imagesc(app.UIAxes, app.dX, app.dY, app.grid);
            %Annotate Plot
            app.UIAxes.XLim=[1 max(app.dX)];
            app.UIAxes.YLim=[1 max(app.dY)];
            app.UIAxes.Title.String='Map of Gale Crater';
            app.UIAxes.XLabel.String='X Position';
            app.UIAxes.YLabel.String='Y Position';
            colorbar(app.UIAxes);
            colormap(app.UIAxes, jet); 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            updateTable(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            T=app.UITable.Data;
            numRow=size(T,1)+1;
            
            newData={' ',0,0,0,false};%initialize new row
            
            app.name(numRow)=newData{1,1};
            app.X(numRow)=newData{1,2};
            app.Y(numRow)=newData{1,3};
            app.time(numRow)=newData{1,4};
            app.plotCheck(numRow)=newData{1,5};
            
            app.UITable.Data=[T;newData];%put the new data with the old data into the table
            
            updateTable(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            T=app.UITable.Data;
            
            %replot the map
            imagesc(app.UIAxes,app.dX,app.dY,app.grid);
            hold(app.UIAxes,'on');
            
            %plot waypoints for which the plot checkbox is checked
            for ii=1:size(T,1)
                if app.plotCheck(ii)==true
                    plot(app.UIAxes, app.X(ii), app.Y(ii),'*k');
                end
            end
            
            %Annotate Plot
            app.UIAxes.XLim=[1 max(app.dX)];
            app.UIAxes.YLim=[1 max(app.dY)];
            app.UIAxes.Title.String='Map of Gale Crater';
            app.UIAxes.XLabel.String='X Position';
            app.UIAxes.YLabel.String='Y Position';
            colorbar(app.UIAxes);
            %Set Drop Down Menu
            app.PlotOptionsDropDown.Value='Waypoints';
        end

        % Value changed function: PlotOptionsDropDown
        function PlotOptionsDropDownValueChanged(app, event)
            value = app.PlotOptionsDropDown.Value;
            switch value
                case 'Map'
                    %plot the grid on the axes component
                    imagesc(app.UIAxes, app.dX, app.dY, app.grid);
                    %Annotate Plot
                    app.UIAxes.XLim=[1 max(app.dX)];
                    app.UIAxes.YLim=[1 max(app.dY)];
                    app.UIAxes.Title.String='Map of Gale Crater';
                    app.UIAxes.XLabel.String='X Position';
                    app.UIAxes.YLabel.String='Y Position';
                    colorbar(app.UIAxes);
                    colormap(app.UIAxes, jet);
                case 'Waypoints'
                    T=app.UITable.Data;
                    imagesc(app.UIAxes,app.dX,app.dY,app.grid);
                    hold(app.UIAxes,'on');
                    for ii=1:size(T,1)
                        if app.plotCheck(ii)==true
                            plot(app.UIAxes, app.X(ii), app.Y(ii),'*k');
                        end
                    end
                    %Annotate Plot
                    app.UIAxes.XLim=[1 max(app.dX)];
                    app.UIAxes.YLim=[1 max(app.dY)];
                    app.UIAxes.Title.String='Map of Gale Crater';
                    app.UIAxes.XLabel.String='X Position';
                    app.UIAxes.YLabel.String='Y Position';
                    colorbar(app.UIAxes);
                case 'Route'
                    %Pull out waypoints to be plotted
                    T=app.UITable.Data;
                    T1=sortrows(T,4);
                    cnt=1;
                    for ii=1:size(T,1);
                        if T1{ii,5}==1
                            app.RouteX(cnt)=T1{ii,2};
                            app.RouteY(cnt)=T1{ii,3};
                            cnt=cnt+1;
                        end
                    end
                    %Plot the map
                    imagesc(app.UIAxes,app.dX,app.dY,app.grid);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.RouteX,app.RouteY,'k');
                    plot(app.UIAxes,app.RouteX(1),app.RouteY(1),'*k');
                    text(app.UIAxes,app.RouteX(1),app.RouteY(1)-2,'start');
                    plot(app.UIAxes,app.RouteX(end),app.RouteY(end),'*k');
                    text(app.UIAxes,app.RouteX(end),app.RouteY(end)-2,'stop');
                    if size(app.RouteX,2)>1
                        app.RouteExists=1;
                    end
            end
        end

        % Button pushed function: CalculateRouteLengthButton
        function CalculateRouteLengthButtonPushed(app, event)
            if app.RouteExists==1
                dist=0;
                for ii=1:length(app.RouteX)-1
                    d1=sqrt((app.RouteX(ii+1)-app.RouteX(ii)).^2 + (app.RouteY(ii+1)-app.RouteY(ii)).^2);
                    d1=d1*app.gridsize;
                    dist=dist+d1;
                end
                app.RouteLengthEditField.Value=dist;
            else
                app.RouteLengthEditField.Value=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'X'; 'Y'; 'Time'; 'Plot'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.PlotOptionsDropDownLabel = uilabel(app.UIFigure);
            app.PlotOptionsDropDownLabel.Text = 'Plot Options';
            app.PlotOptionsDropDown = uidropdown(app.UIFigure);
            app.PlotOptionsDropDown.Items = {'Map', 'Waypoints', 'Route'};
            app.PlotOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotOptionsDropDownValueChanged, true);
            app.PlotOptionsDropDown.Value = 'Map';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RouteLengthEditFieldLabel = uilabel(app.UIFigure);
            app.RouteLengthEditFieldLabel.Text = 'Route Length';
            app.RouteLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateRouteLengthButton = uibutton(app.UIFigure, 'push');
            app.CalculateRouteLengthButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRouteLengthButtonPushed, true);
            app.CalculateRouteLengthButton.Text = 'Calculate Route Length';
            app.RoverRoutePlannerLabel = uilabel(app.UIFigure);
            app.RoverRoutePlannerLabel.Text = 'Rover Route Planner';
            app.WaypointsLabel = uilabel(app.UIFigure);
            app.WaypointsLabel.Text = 'Waypoints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoverRoute

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end